classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ForceCheckBox               matlab.ui.control.CheckBox
        EMGCheckBox                 matlab.ui.control.CheckBox
        ForceplatenumberLabel       matlab.ui.control.Label
        JustmotionCheckBox          matlab.ui.control.CheckBox
        TreadmillForceCombinationCheckBox  matlab.ui.control.CheckBox
        F5                          matlab.ui.control.CheckBox
        F4                          matlab.ui.control.CheckBox
        F3                          matlab.ui.control.CheckBox
        F2                          matlab.ui.control.CheckBox
        F1                          matlab.ui.control.CheckBox
        NoForceCombinationCheckBox  matlab.ui.control.CheckBox
        RunButton                   matlab.ui.control.Button
        OvergroundForceCombinationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        ForceComFlage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

        ForceFlage=app.JustmotionCheckBox.Value;
        EMGFlag=app.EMGCheckBox.Value;
        MotionFlag=app.JustmotionCheckBox.Value;
%         ForceComFlage=app.OvergroundForceCombinationCheckBox.Value;
        [r,ForceplateNum]=find([app.F1.Value app.F2.Value app.F3.Value app.F4.Value app.F5.Value]==1);
%         ForceplateNum=app.ForcePlatenumberEditField_2.Value;
        [fname,folder] = uigetfile('*.c3d');
        Process_Data(fname,folder,MotionFlag,EMGFlag,ForceFlage,app.ForceComFlage,ForceplateNum)
        end

        % Value changed function: NoForceCombinationCheckBox
        function NoForceCombinationCheckBoxValueChanged(app, event)
            value = app.NoForceCombinationCheckBox.Value;
            app.OvergroundForceCombinationCheckBox.Enable=~value;
            app.TreadmillForceCombinationCheckBox.Enable=~value;
        
            
            app.ForceComFlage=0;
        end

        % Callback function
        function Loadc3dButtonPushed(app, event)
        [fname,folder] = uigetfile('*.c3d');
        app.ForceplatenumberLabel.Text=[folder fname];
        end

        % Value changed function: OvergroundForceCombinationCheckBox
        function OvergroundForceCombinationCheckBoxValueChanged(app, event)
            value = app.OvergroundForceCombinationCheckBox.Value;
            app.NoForceCombinationCheckBox.Enable=~value;
            app.TreadmillForceCombinationCheckBox.Enable=~value;
            app.F4.Enable=~value;
            app.F4.Value=0;
            app.F5.Enable=~value;
            app.F5.Value=0;
            app.ForceComFlage=1;
        end

        % Value changed function: TreadmillForceCombinationCheckBox
        function TreadmillForceCombinationCheckBoxValueChanged(app, event)
            value = app.TreadmillForceCombinationCheckBox.Value;
            app.OvergroundForceCombinationCheckBox.Enable=~value;
            app.NoForceCombinationCheckBox.Enable=~value;
            app.ForceComFlage=2;
            app.F1.Enable=~value;
            app.F1.Value=0;
            app.F2.Enable=~value;
            app.F2.Value=0;
            app.F3.Enable=~value;
            app.F3.Value=0;
        end

        % Value changed function: ForceCheckBox
        function ForceCheckBoxValueChanged(app, event)
            value = app.ForceCheckBox.Value;
            app.F1.Enable=value;
            app.F1.Value=0;
            app.F2.Enable=value;
            app.F2.Value=0;
            app.F3.Enable=value;
            app.F3.Value=0;
            app.F4.Enable=value;
            app.F4.Value=0;
            app.F5.Value=0;
            app.F5.Enable=value;
            app.OvergroundForceCombinationCheckBox.Enable=value;
            app.NoForceCombinationCheckBox.Enable=value;
            app.TreadmillForceCombinationCheckBox.Enable=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OvergroundForceCombinationCheckBox = uicheckbox(app.UIFigure);
            app.OvergroundForceCombinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OvergroundForceCombinationCheckBoxValueChanged, true);
            app.OvergroundForceCombinationCheckBox.Text = 'Overground Force Combination';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NoForceCombinationCheckBox = uicheckbox(app.UIFigure);
            app.NoForceCombinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoForceCombinationCheckBoxValueChanged, true);
            app.NoForceCombinationCheckBox.Text = 'No Force Combination';
            app.F1 = uicheckbox(app.UIFigure);
            app.F1.Text = '1';
            app.F2 = uicheckbox(app.UIFigure);
            app.F2.Text = '2';
            app.F3 = uicheckbox(app.UIFigure);
            app.F3.Text = '3';
            app.F4 = uicheckbox(app.UIFigure);
            app.F4.Text = '4';
            app.F5 = uicheckbox(app.UIFigure);
            app.F5.Text = '5';
            app.TreadmillForceCombinationCheckBox = uicheckbox(app.UIFigure);
            app.TreadmillForceCombinationCheckBox.ValueChangedFcn = createCallbackFcn(app, @TreadmillForceCombinationCheckBoxValueChanged, true);
            app.TreadmillForceCombinationCheckBox.Text = 'Treadmill Force Combination';
            app.JustmotionCheckBox = uicheckbox(app.UIFigure);
            app.JustmotionCheckBox.Text = 'Just motion';
            app.ForceplatenumberLabel = uilabel(app.UIFigure);
            app.ForceplatenumberLabel.Text = 'Force plate number';
            app.EMGCheckBox = uicheckbox(app.UIFigure);
            app.EMGCheckBox.Text = 'EMG';
            app.ForceCheckBox = uicheckbox(app.UIFigure);
            app.ForceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceCheckBoxValueChanged, true);
            app.ForceCheckBox.Text = 'Force';
            app.ForceCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end